classdef i2i_app_ultra_mzml < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        i2iUIFigure                    matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        TargetedTab                    matlab.ui.container.Tab
        FiletypeDropDown               matlab.ui.control.DropDown
        FiletypeDropDownLabel          matlab.ui.control.Label
        ColorbarCheckBox               matlab.ui.control.CheckBox
        AnalytenameEditField           matlab.ui.control.EditField
        AnalytenameEditFieldLabel      matlab.ui.control.Label
        ScanfilterDropDown             matlab.ui.control.DropDown
        ScanfilterDropDownLabel        matlab.ui.control.Label
        ColormapDropDown               matlab.ui.control.DropDown
        ColormapDropDownLabel          matlab.ui.control.Label
        RelativeScaleSlider            matlab.ui.control.Slider
        RelativeScaleSliderLabel       matlab.ui.control.Label
        mzListBox                      matlab.ui.control.ListBox
        mzListBoxLabel                 matlab.ui.control.Label
        SaveimageButton                matlab.ui.control.Button
        SavematFileButton              matlab.ui.control.Button
        SaveCSVFileButton              matlab.ui.control.Button
        ToleranceEditField             matlab.ui.control.NumericEditField
        ToleranceEditFieldLabel        matlab.ui.control.Label
        ofanalytesEditField            matlab.ui.control.NumericEditField
        ofanalytesEditFieldLabel       matlab.ui.control.Label
        offilesEditField               matlab.ui.control.NumericEditField
        offilesEditFieldLabel          matlab.ui.control.Label
        LoadmzMLFilesButton            matlab.ui.control.Button
        ResetAllButton                 matlab.ui.control.Button
        Lamp                           matlab.ui.control.Lamp
        LoadAnalyteListButton          matlab.ui.control.Button
        Lamp_2                         matlab.ui.control.Lamp
        FindPeaksButton                matlab.ui.control.Button
        Lamp_3                         matlab.ui.control.Lamp
        ResetDataButton                matlab.ui.control.Button
        EditField                      matlab.ui.control.EditField
        Button                         matlab.ui.control.Button
        Button_2                       matlab.ui.control.Button
        display_mz                     matlab.ui.control.NumericEditField
        NormalizationPanel             matlab.ui.container.Panel
        EditField_2                    matlab.ui.control.NumericEditField
        mzCheckBox                     matlab.ui.control.CheckBox
        TICCheckBox                    matlab.ui.control.CheckBox
        AbsoluteScalePanel             matlab.ui.container.Panel
        EnableAbsoluteScaleCheckBox    matlab.ui.control.CheckBox
        UpperLimitEditField            matlab.ui.control.NumericEditField
        UpperLimitEditFieldLabel       matlab.ui.control.Label
        LowerLimitEditField            matlab.ui.control.NumericEditField
        LowerLimitEditFieldLabel       matlab.ui.control.Label
        AspectratioPanel               matlab.ui.container.Panel
        UpdateaspectratioButton        matlab.ui.control.Button
        StepsizemEditField             matlab.ui.control.NumericEditField
        ProbespeedmsEditField          matlab.ui.control.NumericEditField
        ProbespeedmsLabel              matlab.ui.control.Label
        StepsizemLabel                 matlab.ui.control.Label
        RegionofintrestPanel           matlab.ui.container.Panel
        SaverawdataCheckBox            matlab.ui.control.CheckBox
        nEditField                     matlab.ui.control.NumericEditField
        nEditFieldLabel                matlab.ui.control.Label
        ClearROIButton                 matlab.ui.control.Button
        medianEditField                matlab.ui.control.NumericEditField
        medianEditFieldLabel           matlab.ui.control.Label
        applytoallCheckBox             matlab.ui.control.CheckBox
        LoadROImaskButton              matlab.ui.control.Button
        SaveROImaskButton              matlab.ui.control.Button
        UpdateROIButton                matlab.ui.control.Button
        EditField_5                    matlab.ui.control.NumericEditField
        EditField_5Label               matlab.ui.control.Label
        meanEditField                  matlab.ui.control.NumericEditField
        meanEditFieldLabel             matlab.ui.control.Label
        GetROIButton                   matlab.ui.control.Button
        UIAxes_2                       matlab.ui.control.UIAxes
        AutomaticcontrastadjustmentPanel  matlab.ui.container.Panel
        EnableCheckBox                 matlab.ui.control.CheckBox
        PercentileEditField            matlab.ui.control.NumericEditField
        PercentileEditFieldLabel       matlab.ui.control.Label
        QuantificationPanel            matlab.ui.container.Panel
        RespfacEditField               matlab.ui.control.NumericEditField
        RespfacEditFieldLabel          matlab.ui.control.Label
        ConcEditField                  matlab.ui.control.NumericEditField
        ConcEditFieldLabel             matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        NontargetedTab                 matlab.ui.container.Tab
        ListBox_2                      matlab.ui.control.ListBox
        ExportdatafromnontargetedcalculationsButton  matlab.ui.control.Button
        FiletypeDropDown_2             matlab.ui.control.DropDown
        FiletypeDropDown_2Label        matlab.ui.control.Label
        ResetDataButton_2              matlab.ui.control.Button
        ClearsavedfeaturesButton_2     matlab.ui.control.Button
        ExportsavedfeaturesButton_2    matlab.ui.control.Button
        SavefeaturetolistButton_2      matlab.ui.control.Button
        SaveimageButton_2              matlab.ui.control.Button
        SavematFileButton_2            matlab.ui.control.Button
        SaveCSVFileButton_2            matlab.ui.control.Button
        numberofmzfeaturesEditFieldLabel_2  matlab.ui.control.Label
        numberofmzfeaturesEditField_2  matlab.ui.control.NumericEditField
        mzfeaturesListBox_2            matlab.ui.control.ListBox
        AspectratioPanel_3             matlab.ui.container.Panel
        UpdateaspectratioButton_3      matlab.ui.control.Button
        StepsizemEditField_2           matlab.ui.control.NumericEditField
        ProbespeedmsEditField_2Label   matlab.ui.control.Label
        StepsizemEditField_2Label      matlab.ui.control.Label
        ProbespeedmsEditField_2        matlab.ui.control.NumericEditField
        EditField_4                    matlab.ui.control.NumericEditField
        mzCheckBox_2                   matlab.ui.control.CheckBox
        ColormapDropDown_3             matlab.ui.control.DropDown
        RelativeScaleSlider_3          matlab.ui.control.Slider
        RelativeScaleSlider_3Label     matlab.ui.control.Label
        ColormapDropDown_3Label        matlab.ui.control.Label
        mzEditField_2                  matlab.ui.control.NumericEditField
        mzEditField_2Label             matlab.ui.control.Label
        AutomaticcontrastadjustmentPanel_3  matlab.ui.container.Panel
        EnableCheckBox_3               matlab.ui.control.CheckBox
        PercentileEditField_2          matlab.ui.control.NumericEditField
        PercentileEditField_2Label     matlab.ui.control.Label
        TICnormCheckBox_2              matlab.ui.control.CheckBox
        Button_6                       matlab.ui.control.Button
        Button_5                       matlab.ui.control.Button
        FeaturedetectionparametersPanel  matlab.ui.container.Panel
        maxintEditField                matlab.ui.control.NumericEditField
        maxintEditFieldLabel           matlab.ui.control.Label
        maxhitEditField                matlab.ui.control.NumericEditField
        maxhitEditFieldLabel           matlab.ui.control.Label
        ComparitorregionButton         matlab.ui.control.Button
        MainregionButton               matlab.ui.control.Button
        LoadregionsButton_2            matlab.ui.control.Button
        refmzEditField                 matlab.ui.control.NumericEditField
        refmzEditFieldLabel            matlab.ui.control.Label
        Switch_2                       matlab.ui.control.Switch
        FoldchangeEditField_2          matlab.ui.control.NumericEditField
        FoldchangeEditField_2Label     matlab.ui.control.Label
        ScanfilterDropDown_3           matlab.ui.control.DropDown
        ScanfilterDropDown_2Label_2    matlab.ui.control.Label
        minintEditField                matlab.ui.control.NumericEditField
        minintEditFieldLabel           matlab.ui.control.Label
        minhitEditField                matlab.ui.control.NumericEditField
        minhitEditFieldLabel           matlab.ui.control.Label
        ppmtolEditField                matlab.ui.control.NumericEditField
        ppmtolEditFieldLabel           matlab.ui.control.Label
        FindpeaksButton                matlab.ui.control.Button
        UIAxes_5                       matlab.ui.control.UIAxes
    end


    properties (Access = private)
        mzxmldata % Description
        time % Array of time values
        peak_data % Detected peaks form mzXML
        scan_one_inten %
        scan_two_inten %
        scan_three_inten %
        scan_four_inten %
        scan_five_inten %
        peakthreshold %
        Analyte_Mass
        Analyte_Mass_String %
        Aligned_Analyte_Matrix %
        displayscan %
        workingdirectory %
        plot_number;
        current_plot
        is_mz
        stored_Analyte_Matrix
        TIC
        is_matrix
        data_tmp
        fs_signal_inten
        fs_analyte_matrix
        fs_time
        fs_time2
        NumberOfScans % Number of scans for each line in MS image
        c_scale_1 % bottom of colour scale for images
        c_scale_2 % top of colour scale for images
        rel_scale_val % Value for relative scale bar for colour scaling
        MHmass
        MS_level
        Polarity
        Scanmode
        tic_filter
        aspect_ratio_trigger
        ROI
        BW
        trigger
        Johan_align
        fs_time_simulated
        Analyte_name
        Analyte_Name_String
        NumScans
        Global_spec
        cellarray_untarget
        index
        i
        mzbins
        region1
        region2
        mz_str
        timematrix
        interesting_feature
        current_image_untarget
        totalion
        current_image_target
        kmeans_mask
        norm_ion
        fs_analyte_matrix_untarget
        fs_signal_inten_untarget
        index_target
        roi_rawdata
        scanfilters
        non_target_list
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.plot_number = 1;
            app.peakthreshold = 10;
            app.current_plot = 1;
            app.plot_number = 1;

        end

        % Button pushed function: LoadmzMLFilesButton
        function LoadmzMLFilesButtonPushed(app, event)
            current = pwd;
            directory = uigetdir;
            app.workingdirectory = directory;
            source_dir = app.workingdirectory;
            source_files = dir(fullfile(source_dir, '*.mzML'));
            app.offilesEditField.Value = length(source_files);
            switch app.offilesEditField.Value
                case 0
                    warndlg('The selected folder does not contain any .mzML files','Error')
                otherwise
                    %try
                    cd(source_dir)
                    app.EditField.Value = 'Busy';
                    app.EditField.BackgroundColor = 'y';
                    drawnow;
                    % load the mzxmlfiles in working folder into matlab

                    [app.data_tmp, app.scanfilters] = load_data_mzml(source_files);
                    %[app.data_tmp, app.NumScans, app.TIC, app.peak_data, app.time] = loadfiles_dev2(source_files,source_dir);


                    app.LoadmzMLFilesButton.Enable = 'off';
                    app.Lamp.Color = [0,1,0];
                    app.EditField.Value = 'Ready';
                    app.EditField.BackgroundColor = 'g';
                    drawnow;
                    app.LoadAnalyteListButton.Enable = 'on';
                    %  app.DatabinningparametersPanel.Enable = 'on';
                    %                         app.Lamp_4.Color = [0,1,0];
                    %                         app.EditField_3.Value = 'Data loaded';

                    ddItems = cell(length(app.scanfilters), 1);
                    for ii = 1 : 1 : length(ddItems)
                        ddItems{ii} = app.scanfilters{ii};
                    end
                    app.ScanfilterDropDown.Items = ddItems;
                    app.ScanfilterDropDown.ItemsData = ddItems;

                    app.ScanfilterDropDown_3.Items = ddItems;
                    app.ScanfilterDropDown_3.ItemsData = ddItems;

                    cd(current)
                    %catch
                    app.EditField.Value = 'Ready';
                    app.EditField.BackgroundColor = 'g';
                    %end
            end

            %ScanEventsEditField_3ValueChanged(app, event)

        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.mzxmldata = [];
            app.time = [];
            app.peak_data = [];
            app.LoadmzMLFilesButton.Enable = 'on';
            app.offilesEditField.Value = 0;
            app.Lamp.Color = [1,1,0];
            app.Lamp_2.Color = [1,1,0];
            app.LoadAnalyteListButton.Enable = 'on';
            app.Aligned_Analyte_Matrix = [];
            app.scan_one_inten = [];
            app.Lamp_3.Color = 'y';
            app.FindPeaksButton.Enable = 'on';
            app.ofanalytesEditField.Value = 0;
            cla(app.UIAxes)

            app.EditField.Value = 'Ready';
            app.EditField.BackgroundColor = 'g';
            app.FindPeaksButton.Enable = 'off';
            %             app.is_mz = app.Analyte_Mass(1);
            %             app.is_matrix = app.stored_Analyte_Matrix{1};

            app.mzListBox.Items = {};
            app.AnalytenameEditField.Value = '';
            app.display_mz.Value = 0.0000;
            app.EditField_2.Value = 0.0000;
            app.meanEditField.Value = 0;
            app.medianEditField.Value = 0;
            app.nEditField.Value = 0;
            app.EditField_5.Value=0;
            cla(app.UIAxes_2)
            app.ConcEditField.Enable = 'off';
            app.LoadAnalyteListButton.Enable = 'off';
        end

        % Button pushed function: LoadAnalyteListButton
        function LoadAnalyteListButtonPushed(app, event)
            app.EditField.Value = 'Busy';
            app.EditField.BackgroundColor = 'y';
            drawnow;
            [analytefile,analytepath] = uigetfile('*.xlsx');
            path_plus_file = strcat(analytepath,'/',analytefile);
            try
                if strlength(path_plus_file) > 2
                    analytes_list = readtable(strcat(path_plus_file));
                    adata = analytes_list.Var1;
                    MHmass2 = adata(1:end,1);
                    names = analytes_list.Var2;

                    app.Analyte_Mass = MHmass2;
                    app.Analyte_name = names;
                    app.Lamp_2.Color = 'g';
                    app.ofanalytesEditField.Value = length(app.Analyte_Mass);
                    app.LoadAnalyteListButton.Enable = 'off';
                    app.FindPeaksButton.Enable = 'on';
                    app.EditField.Value = 'Ready';
                    app.EditField.BackgroundColor = 'g';
                end
            catch
                if strlength(path_plus_file) > 2
                    analytes_list = readtable(strcat(path_plus_file));
                    adata = analytes_list.Var1;
                    MHmass2 = adata(1:end,1);
                    app.Analyte_Mass = MHmass2;
                    app.Analyte_name = [];
                    app.Lamp_2.Color = 'g';
                    app.ofanalytesEditField.Value = length(app.Analyte_Mass);
                    app.LoadAnalyteListButton.Enable = 'off';
                    app.FindPeaksButton.Enable = 'on';
                    app.EditField.Value = 'Ready';
                    app.EditField.BackgroundColor = 'g';
                end
            end
            app.Analyte_Mass_String = string(app.Analyte_Mass);
            app.Analyte_Name_String = string(app.Analyte_name);
        end

        % Value changed function: ofanalytesEditField
        function ofanalytesEditFieldValueChanged(app, event)
            app.FindPeaksButton.Value = 1;

        end

        % Button pushed function: FindPeaksButton
        function FindPeaksButtonPushed(app, event)
            % Extract masses and intensities for full scan
            app.EditField.Value = 'Busy';
            app.EditField.BackgroundColor = 'y';
            drawnow;
            app.MHmass = app.Analyte_Mass;
            cutoffppm = app.peakthreshold;
            [app.peak_data,app.time,app.tic_filter,app.NumberOfScans] = sort_data_mzml(app.ScanfilterDropDown.Value,app.data_tmp);
            app.tic_filter = tic_matrix(app.tic_filter,app.NumberOfScans);
            [app.fs_analyte_matrix, app.fs_signal_inten] = mass_intensity_dev2(app.MHmass,cutoffppm,app.peak_data,app.NumberOfScans);


            app.Lamp_3.Color = 'g';

            app.EditField.Value = 'Ready';
            app.EditField.BackgroundColor = 'g';
            %app.scan_one_inten = app.fs_analyte_matrix;
            app.TICCheckBox.Enable = 'on';
            app.mzCheckBox.Enable = 'on';
            app.EditField_2.Enable = 'on';
            app.EditField_2.Value = app.Analyte_Mass(1);
            app.is_mz = app.Analyte_Mass(1);

            app.TICCheckBox.Value = 0;
            app.mzCheckBox.Value = 0;


            % Populate the mass list next to the plot
            masses = app.Analyte_Mass;
            masses_string = string(masses);
            app.time
            app.mzListBox.Items = masses_string;
            try
                app.AnalyteIonsNames.Items = app.Analyte_name;
                [app.fs_analyte_matrix, app.fs_time2] = scanevent3(app.fs_signal_inten,app.time,app.MHmass);


                [app.fs_analyte_matrix,app.fs_time_simulated] = align_dev2(app.fs_time2,app.fs_analyte_matrix);
                app.Johan_align = align_dev2(app.fs_time2,app.tic_filter);
                app.stored_Analyte_Matrix = app.fs_analyte_matrix;

            catch
                [app.fs_analyte_matrix, app.fs_time2] = scanevent3(app.fs_signal_inten,app.time,app.MHmass);


                [app.fs_analyte_matrix,app.fs_time_simulated] = align_dev2(app.fs_time2,app.fs_analyte_matrix);
                app.Johan_align = align_dev2(app.fs_time2,app.tic_filter);
                app.stored_Analyte_Matrix = app.fs_analyte_matrix;

            end


            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.fs_analyte_matrix{1},[],'all');
            imagesc(app.UIAxes,app.fs_analyte_matrix{1})
            try
                clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                app.UIAxes.XLim = [0,size(app.fs_analyte_matrix{1},2)];
            catch
                clim(app.UIAxes,'auto');
                app.UIAxes.XLim = [0,size(app.fs_analyte_matrix{1},2)];
            end
            app.display_mz.Value = app.Analyte_Mass(app.plot_number);
            app.current_plot = app.current_image_target;
            colormap(app.UIAxes,app.ColormapDropDown.Value);
            app.mzListBox.Items = app.Analyte_Mass_String;
            app.mzListBox.Value = app.Analyte_Mass_String{1};
            mzListBoxValueChangedd(app, event)
        end

        % Button pushed function: ResetDataButton
        function ResetDataButtonPushed(app, event)
            app.Lamp_2.Color = [1,1,0];
            app.LoadAnalyteListButton.Enable = 'on';
            app.fs_analyte_matrix  = [];
            app.scan_one_inten = [];
            app.Lamp_3.Color = 'y';
            app.FindPeaksButton.Enable = 'on';
            app.ofanalytesEditField.Value = 0;
            cla(app.UIAxes)

            app.EditField.Value = 'Ready';
            app.EditField.BackgroundColor = 'g';
            app.FindPeaksButton.Enable = 'off';
            app.is_mz = app.Analyte_Mass(1);
            app.is_matrix = app.stored_Analyte_Matrix{1};
            app.mzListBox.Items = {};
            app.display_mz.Value = 0.0000;
            app.EditField_2.Value = 0.0000;
            app.mzListBox.Items = {};
            app.AnalytenameEditField.Value = '';
            app.display_mz.Value = 0.0000;
            app.EditField_2.Value = 0.0000;
            app.meanEditField.Value = 0;
            app.medianEditField.Value = 0;
            app.nEditField.Value = 0;
            app.EditField_5.Value=0;
            cla(app.UIAxes_2)
            app.ConcEditField.Enable = 'off';
        end

        % Callback function
        function SelectmzXMLDirectoryButtonPushed(app, event)
            dir = uigetdir;
            app.workingdirectory = dir;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            number_of_images = 1:1:length(app.Analyte_Mass);


            if app.plot_number < length(app.Analyte_Mass)
                app.plot_number = app.plot_number + 1;
                app.mzListBox.Value = app.mzListBox.Items(app.plot_number);
            else
                app.plot_number = 1;
                app.mzListBox.Value = app.mzListBox.Items(app.plot_number);
            end


            if app.applytoallCheckBox.Value == 1
                UpdateROIButtonPushed(app,event)
            end
            mzListBoxValueChangedd(app,event)
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            number_of_images = 1:1:length(app.Analyte_Mass);


            if app.plot_number == 1
                app.plot_number =  max(number_of_images);
                app.mzListBox.Value = app.mzListBox.Items(app.plot_number);
            else
                app.plot_number = app.plot_number - 1;
                app.mzListBox.Value = app.mzListBox.Items(app.plot_number);
            end

            if app.applytoallCheckBox.Value == 1
                UpdateROIButtonPushed(app,event)
            end
            mzListBoxValueChangedd(app,event)
        end

        % Value changed function: ToleranceEditField
        function ToleranceEditFieldValueChanged(app, event)
            app.peakthreshold = app.ToleranceEditField.Value;
            app.Lamp_3.Color = 'y';
        end

        % Button pushed function: SaveimageButton
        function SaveimageButtonPushed(app, event)
            fig = figure('visible','off');
            h = copyUIAxes(app.UIAxes,fig);

            current_dir = pwd;
            dir = uigetdir;
            cd(dir)

            a = exist(strcat(num2str(app.Analyte_Mass(app.plot_number)),app.FiletypeDropDown.Value),'file');

            if a == 2
                exportgraphics(app.UIAxes,strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                    datestr(now,'mm-dd-yyyy HH-MM'),app.FiletypeDropDown.Value),...
                    'BackgroundColor','none','ContentType','vector','Resolution',300);

            else
                exportgraphics(app.UIAxes,strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                    app.FiletypeDropDown.Value),'BackgroundColor','none','ContentType','vector','Resolution',300);


            end
            cd(current_dir)
        end

        % Button pushed function: SaveCSVFileButton
        function SaveCSVFileButtonPushed(app, event)
            app.current_image_target

            %             a = exist('i2i Images','file');
            %             if a ~= 7
            %                 mkdir('i2i Images')
            %             end
            current_dir = pwd;
            dir = uigetdir;
            cd(dir)
            a = exist(strcat(num2str(app.Analyte_Mass(app.plot_number)),'.csv'),'file');
            if a == 2
                csvwrite(strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                    datestr(now,'mm-dd-yyyy HH-MM'),'.csv'),app.current_image_target)
            else
                csvwrite(strcat(num2str(app.Analyte_Mass(app.plot_number)),'.csv'),app.current_image_target)
            end
            cd(current_dir)
        end

        % Button pushed function: SavematFileButton
        function SavematFileButtonPushed(app, event)
            %             a = exist('i2i Images','file');
            %             if a ~= 7
            %                 mkdir('i2i Images')
            %             end
            current_dir = pwd;
            dir = uigetdir;
            cd(dir)
            a = exist(strcat(num2str(app.Analyte_Mass(app.plot_number)),'.mat'),'file');
            if a == 2
                cp = app.current_image_target;
                save(strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                    datestr(now,'mm-dd-yyyy HH-MM'),'.mat'),'cp')
            else
                cp = app.current_image_target;
                save(strcat(num2str(app.Analyte_Mass(app.plot_number)),'.mat'),'cp')
            end
            cd(current_dir)
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            norm_mz = app.EditField_2.Value;
            mzvalues = app.Analyte_Mass;
            [~,idx] = min(abs(mzvalues - norm_mz));
            app.EditField_2.Value = mzvalues(idx);
            app.is_mz = mzvalues(idx);
            mzCheckBoxValueChanged(app,event);


        end

        % Value changed function: mzCheckBox
        function mzCheckBoxValueChanged(app, event)
            am = app.fs_analyte_matrix;

            r_am = cell(length(am),1);
            app.ConcEditField.Enable = 'on';
            app.RespfacEditField.Enable = 'on';

            switch app.mzCheckBox.Value
                case 1
                    [~,idx_is] = min(abs(app.EditField_2.Value-app.Analyte_Mass));
                    for j = 1:length(am)
                        r_am{j,1} = am{j} ./ am{idx_is}.*app.ConcEditField.Value.*app.RespfacEditField.Value;
                    end
                    app.fs_analyte_matrix = r_am;

                    app.TICCheckBox.Enable = 'off';
                case 0
                    app.fs_analyte_matrix = app.stored_Analyte_Matrix;
                    app.TICCheckBox.Enable = 'on';
                    app.ConcEditField.Enable = 'off';
                    app.RespfacEditField.Enable = 'off';
            end

            mzListBoxValueChangedd(app,event)
        end

        % Value changed function: TICCheckBox
        function TICCheckBoxValueChanged(app, event)

            am = app.fs_analyte_matrix;
            r_am = cell(length(am),1);
            tic_m = app.Johan_align{1};

            switch app.TICCheckBox.Value
                case 1
                    for j = 1:length(am)
                        r_am{j,1} = am{j} ./ tic_m;
                    end
                    app.fs_analyte_matrix = r_am;
                    app.mzCheckBox.Enable = 'off';

                case 0
                    app.fs_analyte_matrix = app.stored_Analyte_Matrix;
                    app.mzCheckBox.Enable = 'on';
            end

            mzListBoxValueChangedd(app,event)
        end

        % Value changed function: mzListBox
        function mzListBoxValueChangedd(app, event)

            masses = app.Analyte_Mass;

            highlighted_mass = app.mzListBox.Value;
            %app.AnalyteIonsNames.Value = highlighted_mass;
            mass = str2double(highlighted_mass);
            [~, mz_idx] = min(abs(masses - mass));
            app.plot_number = mz_idx;
            app.current_image_target = app.fs_analyte_matrix{app.plot_number};

            switch app.EnableAbsoluteScaleCheckBox.Value
                case 0
                    app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_target,[],'all')...
                        *0.01*app.RelativeScaleSlider.Value;
                case 1
                    app.c_scale_1 = app.LowerLimitEditField.Value;
                    app.c_scale_2 = app.UpperLimitEditField.Value;

            end

            switch app.EnableCheckBox.Value
                case 0
                    switch app.EnableAbsoluteScaleCheckBox.Value
                        case 0
                            try
                                app.c_scale_2 = max(app.current_image_target,[],'all')...
                                    *0.01*app.RelativeScaleSlider.Value;
                                    clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                                app.RelativeScaleSlider.Enable = 'on';
                                app.EnableAbsoluteScaleCheckBox.Enable = 'on';
                            catch
                                clim(app.UIAxes,"auto");
                                app.RelativeScaleSlider.Enable = 'on';
                                app.EnableAbsoluteScaleCheckBox.Enable = 'on';
                            end
                        case 1
                            app.c_scale_1 = app.LowerLimitEditField.Value;
                            app.c_scale_2 = app.UpperLimitEditField.Value;
                    end

                case 1
                    A = app.fs_analyte_matrix{app.plot_number};
                    A(isinf(A)) = NaN;
                    n = app.PercentileEditField.Value;
                    avg = prctile(A,n,'all');
                    %sigma = std(A,[],'all','omitnan');
                    if gt(avg,app.c_scale_1)
                        app.c_scale_2 = avg;
                        app.RelativeScaleSlider.Enable = 'off';
                        app.EnableAbsoluteScaleCheckBox.Enable = 'off';
                    else
                        app.c_scale_2 = avg+1;
                        app.RelativeScaleSlider.Enable = 'off';
                        app.EnableAbsoluteScaleCheckBox.Enable = 'off';
                    end

            end

            imagesc(app.UIAxes,app.current_image_target)
            try
                clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
            catch
                clim(app.UIAxes,'auto');
            end
            app.UIAxes.XLim = [0,size(app.current_image_target,2)];
            app.display_mz.Value = app.Analyte_Mass(app.plot_number);
            app.current_plot = app.current_image_target;

            if app.applytoallCheckBox.Value == 1
                UpdateROIButtonPushed(app,event)
            end

            if size(app.Analyte_name,1)>0
                if isempty(app.Analyte_name{app.plot_number})
                    app.AnalytenameEditField.Value = 'Not annotated';
                else
                    app.AnalytenameEditField.Value = app.Analyte_name{app.plot_number};
                end
            else
                app.AnalytenameEditField.Value = 'Not annotated';
            end
        end

        % Value changed function: RelativeScaleSlider
        function RelativeScaleSliderValueChanged(app, event)
            switch app.RelativeScaleSlider.Value
                case 0
                    app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_target,[],'all')...
                        *0.01*app.RelativeScaleSlider.Value;
x                    clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
                case 1
                    app.c_scale_1 = app.LowerLimitEditField.Value;
                    app.c_scale_2 = app.UpperLimitEditField.Value;
                    clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
            end


            %             if app.EnableAbsoluteScaleCheckBox.Value == 0
            %                 app.c_scale_1 = 0;
            %                 app.c_scale_2 = max(app.current_image_target,[],'all')...
            %                     *0.01*app.RelativeScaleSlider.Value;
            %                 clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
            %             end
            %
            %             if app.EnableAbsoluteScaleCheckBox.Value == 1
            %                 app.c_scale_1 = app.LowerLimitEditField.Value;
            %                 app.c_scale_2 = app.UpperLimitEditField.Value;
            %                 clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
            %             end


        end

        % Value changed function: EnableAbsoluteScaleCheckBox
        function EnableAbsoluteScaleCheckBoxValueChanged2(app, event)
            value = app.EnableAbsoluteScaleCheckBox.Value;

            switch value
                case 1
                    app.LowerLimitEditField.Enable = 'on';
                    app.UpperLimitEditField.Enable = 'on';
                    app.EnableCheckBox.Enable = 'off';
                    app.RelativeScaleSlider.Enable = 'off';

                    app.c_scale_2 = app.UpperLimitEditField.Value;
                    app.c_scale_1 = app.LowerLimitEditField.Value;
                    clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                case 0
                    app.LowerLimitEditField.Enable = 'off';
                    app.UpperLimitEditField.Enable = 'off';
                    app.EnableCheckBox.Enable = 'on';
                    app.RelativeScaleSlider.Enable = 'on';
                    app.c_scale_2 = max(app.fs_analyte_matrix{app.plot_number},[],'all')...
                        *0.01*app.RelativeScaleSlider.Value;
                    clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
            end


        end

        % Button pushed function: UpdateaspectratioButton
        function UpdateaspectratioButtonPushed(app, event)
            x = aspect_ratio(app.time,app.ProbespeedmsEditField.Value,app.StepsizemEditField.Value);
            pbaspect(app.UIAxes,[x 1 1])
            pbaspect(app.UIAxes_2,[x 1 1])

        end

        % Callback function: EditField_5, GetROIButton, meanEditField, 
        % ...and 1 other component
        function ROIButtonPushed(app, event)

            app.BW = 0;
            cla(app.UIAxes_2)
            app.ROI = drawfreehand(app.UIAxes);
            app.BW = createMask(app.ROI);
            mask = app.fs_analyte_matrix{app.plot_number}.*app.BW;
            mask(mask == 0)=NaN;
            mask(isinf(mask)) = NaN;
            mask(isnan(mask)) = [];


            app.roi_rawdata = reshape(mask,[1, size(mask,1)*size(mask,2)]);
            app.roi_rawdata = sort(app.roi_rawdata);
            app.roi_rawdata=unique(app.roi_rawdata);

            app.meanEditField.Value = mean(app.roi_rawdata,'all','omitnan');
            app.EditField_5.Value = std(app.roi_rawdata,0,'all','omitnan');
            app.medianEditField.Value = median(app.roi_rawdata,'all','omitnan');
            app.UpdateROIButton.Enable = 'on';
            app.nEditField.Value = length(app.roi_rawdata);
            app.trigger = 1;
            imagesc(app.UIAxes_2,app.BW);
            colormap(app.UIAxes_2,app.ColormapDropDown.Value);
            app.applytoallCheckBox.Enable = 'on';
        end

        % Button pushed function: UpdateROIButton
        function UpdateROIButtonPushed(app, event)
            if app.trigger == 0
                app.BW = createMask(app.ROI);
                mask = app.fs_analyte_matrix{app.plot_number}.*app.BW;
                mask(mask == 0) = NaN;
                mask(isinf(mask)) = NaN;
                app.meanEditField.Value = mean(mask,'all','omitnan');
                app.EditField_5.Value = std(mask,0,'all','omitnan');
                app.medianEditField.Value = median(mask,'all','omitnan');
            end

            if app.trigger == 1
                mask = app.fs_analyte_matrix{app.plot_number}.*app.BW;
                mask(mask == 0) = NaN;
                mask(isinf(mask)) = NaN;
                app.meanEditField.Value = mean(mask,'all','omitnan');
                app.EditField_5.Value = std(mask,0,'all','omitnan');
                app.medianEditField.Value = median(mask,'all','omitnan');

            end
        end

        % Button pushed function: SaveROImaskButton
        function SaveROImaskButtonPushed(app, event)
            a = app.BW;
            time_matrix_roi = app.BW.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            uisave('a','filename for ROI mask')
            uisave('list','filename for timestamps of ROI')
            if app.SaverawdataCheckBox.Value == 1
                current_dir = pwd;
                dir = uigetdir;
                cd(dir)
                a = exist(strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                    'roi_rawdata','.csv'),'file');
                if a == 2
                    writematrix(app.roi_rawdata,strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                        'roi_rawdata',datestr(now,'mm-dd-yyyy HH-MM'),'.csv'))
                else
                    writematrix(app.roi_rawdata,strcat(num2str(app.Analyte_Mass(app.plot_number)),...
                        'roi_rawdata','.csv'))
                end
                cd(current_dir)
            end

        end

        % Value changed function: ColormapDropDown
        function ColormapDropDownValueChanged(app, event)
            colormap(app.UIAxes,app.ColormapDropDown.Value);

        end

        % Callback function
        function ScanEventsEditFieldValueChanged(app, event)


            ddItems = cell(app.ScanEventsEditField.Value, 1);
            for ii = 1 : 1 : length(ddItems)
                ddItems{ii} = sprintf('%d', ii);
            end
            app.ScanfilterDropDown.Items = ddItems;
            app.ScanfilterDropDown.ItemsData = 1 : length(ddItems);
        end

        % Button pushed function: LoadROImaskButton
        function LoadROImaskButtonPushed(app, event)
            [analytefile,analytepath] = uigetfile('.');
            path_plus_file = strcat(analytepath,analytefile);
            a = load(path_plus_file);
            app.BW = a.a;
            app.trigger = 1;
            imagesc(app.UIAxes_2,app.BW)
            colormap(app.UIAxes_2,app.ColormapDropDown.Value);
            app.UpdateROIButton.Enable = 'on';
            app.applytoallCheckBox.Enable = 'on';
            UpdateROIButtonPushed(app,event)
        end

        % Value changed function: applytoallCheckBox
        function applytoallCheckBoxValueChanged(app, event)
            value = app.applytoallCheckBox.Value;

            if value == 1
                app.UpdateROIButton.Enable = 'on';
            end

            if value == 0
                app.UpdateROIButton.Enable = 'off';
                app.meanEditField.Value = 0;
                app.EditField_5.Value = 0;
                app.medianEditField.Value = 0;
                cla(app.UIAxes_2)
            end
        end

        % Button pushed function: ClearROIButton
        function ClearROIButtonPushed(app, event)
            app.trigger = 0;
            app.meanEditField.Value = 0;
            app.EditField_5.Value = 0;
            app.medianEditField.Value = 0;
        end

        % Callback function
        function AnalyteIonsNamesValueChanged(app, event)
            app.AnalyteIonsNames.ItemsData = [];
            app.AnalyteIonsNames.Items = app.Analyte_name;
        end

        % Value changed function: ConcEditField
        function ConcEditFieldValueChanged(app, event)

            am = app.fs_analyte_matrix;
            [~,idx_is] = min(abs(app.EditField_2.Value-app.Analyte_Mass));
            r_am = cell(length(am),1);
            app.ConcEditField.Enable = 'on';
            if app.mzCheckBox.Value == 1
                for j = 1:length(am)
                    r_am{j,1} = am{j} ./ am{idx_is}.*app.ConcEditField.Value.*app.RespfacEditField.Value;
                end
                app.fs_analyte_matrix = r_am;

                app.TICCheckBox.Enable = 'off';
            end

            if app.mzCheckBox.Value == 0
                app.fs_analyte_matrix = app.stored_Analyte_Matrix;
                app.TICCheckBox.Enable = 'on';

            end
            UpdateROIButtonPushed(app,event)
            mzCheckBoxValueChanged(app, event)
        end

        % Value changed function: PercentileEditField
        function PercentileEditFieldValueChanged(app, event)

            A = app.fs_analyte_matrix{app.plot_number};

            A(isinf(A)) = NaN;

            n = app.PercentileEditField.Value;
            avg = prctile(A,n,'all');
            %sigma = std(A,[],'all','omitnan');
            if gt(avg,app.c_scale_1)
                app.c_scale_2 = avg;
                clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                app.RelativeScaleSlider.Enable = 'off';
                app.EnableAbsoluteScaleCheckBox.Enable = 'off';
            else
                app.c_scale_2 = avg+1;
                clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                app.RelativeScaleSlider.Enable = 'off';
                app.EnableAbsoluteScaleCheckBox.Enable = 'off';
            end
            %clim(app.UIAxes,[app.c_scale_1, avg]);


        end

        % Callback function
        function BindataButtonPushed(app, event)
            m = app.ScanEventsEditField_2.Value;
            n = app.ScanfilterDropDown_2.Value;
            start_mz = app.startmzEditField.Value;
            end_mz =app.endmzEditField.Value;
            ppm_width = app.widthppmEditField.Value;

            [app.mzbins,app.cellarray_untarget] = bin_data_dev2(start_mz,end_mz,ppm_width,app.peak_data,n,m);
            average_spectrum = average_spectrum_bin(app.cellarray_untarget);

            stem(app.average_massspec_figure,app.mzbins,average_spectrum,'marker','none');

            [~,idx] = max(average_spectrum);

            [highest_ion,app.fs_time_simulated] = align_one_feature_dev(app.timematrix,app.cellarray_untarget,idx,n,m);

            app.current_image_untarget = cell2mat(highest_ion);

            imagesc(app.UIAxes_3,app.current_image_untarget);
            colormap(app.UIAxes_3,app.ColormapDropDown_3.Value);
            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                *0.01*app.RelativeScaleSlider_2.Value;
            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);
            app.totalion =align_tic(app.timematrix(:,n:m:end),app.TIC(:,n:m:end));
            app.mzEditField.Value = app.mzbins(idx);
            app.mzfeaturesListBox.Items = {};
            app.index = [];
            app.Lamp_4.Color = [0.90,0.90,0.90];
            app.UntargetedworkflowboxPanel.Enable = 'on';
        end

        % Callback function
        function RununtargetedcomparisonButtonPushed(app, event)

            m = app.ScanEventsEditField_2.Value;
            n = app.ScanfilterDropDown_2.Value;
            app.i = 1;
            app.index = [];
            k = app.RSD100EditField.Value;
            app.mzfeaturesListBox.Items = {};
            [a1,a2,s1] = extract_regions_untarget(app.region1, app.region2, app.timematrix,app.cellarray_untarget,n,m,k);
            c = a1./a2;
            rsd = s1./a1;
            c(isinf(c))=NaN;
            P = c>app.FoldchangeEditField.Value;
            P2 = rsd<app.RSD100EditField.Value;
            P3 = P.*P2;
            app.index = find(P3);
            try
                fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                app.current_image_untarget = cell2mat(fs_aligned_matrix);
                imagesc(app.UIAxes_3,app.current_image_untarget);
                app.mzEditField.Value = app.mzbins(app.index(app.i));
                app.mz_str = [];
                length_mz = 1:length(app.index);
                app.mz_str = string(app.mzbins(app.index(length_mz)));

                app.mzfeaturesListBox.Items = app.mz_str;
                app.numberofmzfeaturesEditField.Value = length(app.index);
                app.c_scale_1 = 0;
                app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                    *0.01*app.RelativeScaleSlider_2.Value;
                clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                %app.Lamp_5.Color = [0,1,0];
            catch
                warndlg('There are no features that satisfy the RSD/100 and Fold change parameters','Error')

            end

        end

        % Callback function
        function Button_3Pushed(app, event)

            m = app.ScanEventsEditField_2.Value;
            n = app.ScanfilterDropDown_2.Value;
            switch app.TICnormCheckBox.Value
                case 0
                    try
                        if app.i < max(length(app.index))
                            app.i=app.i+1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix);
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                        else
                            app.i = 1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix);
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                        end
                    catch

                    end


                case 1
                    try
                        if app.i < max(length(app.index))
                            app.i=app.i+1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix)./app.totalion;
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                        else
                            app.i = 1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix)./app.totalion;
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                        end
                    catch

                    end
            end

        end

        % Callback function
        function Button_4Pushed(app, event)

            m = app.ScanEventsEditField_2.Value;
            n = app.ScanfilterDropDown_2.Value;
            switch app.TICnormCheckBox.Value
                case 0
                    try
                        if app.i >= 1.5
                            app.i=app.i-1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix);
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                        else
                            app.i = 1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix);
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                        end
                    catch

                    end


                case 1
                    try
                        if app.i < max(length(app.index))
                            app.i=app.i-1;
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix)./app.totalion;
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                        else
                            app.i = length(app.index);
                            fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                            app.current_image_untarget = cell2mat(fs_aligned_matrix)./app.totalion;
                            imagesc(app.UIAxes_3,app.current_image_untarget);
                            app.mzEditField.Value = app.mzbins(app.index(app.i));
                            app.mzfeaturesListBox.Value = app.mz_str(app.i);
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                        end
                    catch

                    end
            end

        end

        % Callback function
        function LoadregionsButtonPushed(app, event)
            [regionfile,regionpath] = uigetfile('.','Select file 1');
            path_plus_file = strcat(regionpath,regionfile);
            load(path_plus_file);
            app.region1 = list;

            [regionfile,regionpath] = uigetfile('.','Select file 2');
            path_plus_file = strcat(regionpath,regionfile);
            load(path_plus_file);
            app.region2 =  list;
            app.RununtargetedcomparisonButton.Enable = 'on';
        end

        % Callback function
        function mzfeaturesListBoxValueChanged(app, event)
            m = app.ScanEventsEditField_2.Value;
            n = app.ScanfilterDropDown_2.Value;
            length_mz = 1:length(app.index);
            masses = app.mzbins(app.index(length_mz));

            app.mzfeaturesListBox.Items = app.mz_str;
            highlighted_mass = app.mzfeaturesListBox.Value;

            mass = str2double(highlighted_mass);
            [~, mz_idx] = min(abs(masses - mass));

            app.i = mz_idx;

            switch app.TICnormCheckBox.Value
                case 0
                    fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                    app.current_image_untarget = cell2mat(fs_aligned_matrix);
                    imagesc(app.UIAxes_3,app.current_image_untarget);
                    app.mzEditField.Value = app.mzbins(app.index(app.i));

                    app.mzEditField.Value = app.mzbins(app.index(app.i));
                    switch app.EnableCheckBox_2.Value
                        case 1
                            A = app.current_image_untarget;

                            A(isinf(A)) = NaN;

                            avg = mean(A,'all','omitnan');
                            sigma = std(A,[],'all','omitnan');
                            n = app.LevelEditField.Value;

                            clim(app.UIAxes_3,[app.c_scale_1, avg+n*sigma]);
                            app.RelativeScaleSlider_2.Enable = 'off';
                        case 0
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                    end

                case 1

                    fs_aligned_matrix = align_one_feature_dev(app.timematrix,app.cellarray_untarget,app.index(app.i),n,m);
                    app.current_image_untarget = cell2mat(fs_aligned_matrix)./app.totalion;
                    imagesc(app.UIAxes_3,app.current_image_untarget);
                    app.mzEditField.Value = app.mzbins(app.index(app.i));
                    app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                        *0.01*app.RelativeScaleSlider_2.Value;
                    clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);
                    app.mzEditField.Value = app.mzbins(app.index(app.i));
                    switch app.EnableCheckBox_2.Value
                        case 1
                            A = app.current_image_untarget;

                            A(isinf(A)) = NaN;

                            avg = mean(A,'all','omitnan');
                            sigma = std(A,[],'all','omitnan');
                            n = app.LevelEditField.Value;

                            clim(app.UIAxes_3,[app.c_scale_1, avg+n*sigma]);
                            app.RelativeScaleSlider_2.Enable = 'off';
                        case 0
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_2.Value;
                            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);

                    end
            end


        end

        % Callback function
        function ScanEventsEditField_2ValueChanged(app, event)
            value = app.ScanEventsEditField_2.Value;
            ddItems = cell(value, 1);
            for ii = 1 : 1 : length(ddItems)
                ddItems{ii} = sprintf('%d', ii);
            end
            app.ScanfilterDropDown_2.Items = ddItems;
            app.ScanfilterDropDown_2.ItemsData = 1 : length(ddItems);
        end

        % Callback function
        function UpdateaspectratioButton_2Pushed(app, event)
            x = aspect_ratio(app.time,app.ProbespeedmicrometerssEditField_2.Value,app.StepsizemicrometersEditField_2.Value);
            pbaspect(app.UIAxes_3,[x 1 1]);
            pbaspect(app.UIAxes_4,[x 1 1]);

        end

        % Callback function
        function Drawregion1ButtonPushed(app, event)
            app.BW = 0;
            app.ROI = drawfreehand(app.UIAxes_3);
            app.BW = createMask(app.ROI);


            time_matrix_roi = app.BW.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            app.region1 = list;

        end

        % Callback function
        function Drawregion2ButtonPushed(app, event)
            app.BW = 0;
            app.ROI = drawfreehand(app.UIAxes_3);
            app.BW = createMask(app.ROI);


            time_matrix_roi = app.BW.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            app.region2 = list;
            app.RununtargetedcomparisonButton.Enable = 'on';

        end

        % Callback function
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            if value == 'Load'
                app.Drawregion1Button.Enable = 'off';
                app.Drawregion2Button.Enable = 'off';
                app.LoadregionsButton.Enable = 'on';
            end
            if value == 'Draw'
                app.LoadregionsButton.Enable = 'off';
                app.Drawregion1Button.Enable = 'on';
                app.Drawregion2Button.Enable = 'on';
            end
            app.RununtargetedcomparisonButton.Enable = 'off';
        end

        % Callback function
        function ExportfeaturesButtonPushed(app, event)
            length_mz = 1:length(app.index);
            features = app.mzbins(app.index(length_mz));
            name = {datestr(now,'mm-dd-yyyy HH-MM'),'.csv'};
            writematrix(features',strjoin(name));
        end

        % Callback function
        function SavefeaturetolistButtonPushed(app, event)
            current_feature = app.mzbins(app.index(app.i));
            app.interesting_feature = [app.interesting_feature current_feature];
            app.ListBox.Items = string(app.interesting_feature);
        end

        % Callback function
        function ExportsavedfeaturesButtonPushed(app, event)

            interest = app.interesting_feature;
            name = {'interesting features',datestr(now,'mm-dd-yyyy HH-MM'),'.csv'};
            writematrix(interest',strjoin(name));
        end

        % Value changed function: RespfacEditField
        function RespfacEditFieldValueChanged(app, event)
            %value = app.RespfacEditField.Value;
            ConcEditFieldValueChanged(app, event)
            
        end

        % Callback function
        function RelativeScaleSlider_2ValueChanging(app, event)
            changingValue = event.Value;

            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                *0.01*changingValue;
            clim(app.UIAxes_3,[app.c_scale_1,app.c_scale_2]);


        end

        % Value changing function: RelativeScaleSlider
        function RelativeScaleSliderValueChanging(app, event)
            changingValue = event.Value;


            if app.EnableAbsoluteScaleCheckBox.Value == 0
                app.c_scale_1 = 0;
                app.c_scale_2 = max(app.current_image_target(~isinf(app.current_image_target)),[],'all')...
                    *0.01*changingValue;
                clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
            end

            if app.EnableAbsoluteScaleCheckBox.Value == 1
                app.c_scale_1 = app.LowerLimitEditField.Value;
                app.c_scale_2 = app.UpperLimitEditField.Value;
                clim(app.UIAxes,[app.c_scale_1,app.c_scale_2]);
            end
        end

        % Callback function
        function ClearsavedfeaturesButtonPushed(app, event)
            choice = questdlg('Are you sure you want to clear feature list?','','Yes','No','');

            switch choice
                case 'Yes'
                    app.interesting_feature = {};
                    app.ListBox.Items = string(app.interesting_feature);
                case 'No'
                    return
                case 'Cancel'
                    return
                case ''
                    return
            end


        end

        % Callback function
        function RelativeScaleSlider_2ValueChanged(app, event)
            %  value = app.RelativeScaleSlider_2.Value;

        end

        % Callback function
        function TICnormCheckBoxValueChanged(app, event)

            mzfeaturesListBoxValueChanged(app,event)
        end

        % Callback function
        function ColormapDropDown_2ValueChanged(app, event)
            value = app.ColormapDropDown_3.Value;
            colormap(app.UIAxes_3,value);
            colormap(app.UIAxes_4,value);
        end

        % Callback function
        function CLEARButtonPushed(app, event)
            evalin('base','clear all')
        end

        % Value changed function: EnableCheckBox
        function EnableCheckBoxValueChanged(app, event)
            value = app.EnableCheckBox.Value;
            switch value
                case 0
                    app.c_scale_2 = max(app.fs_analyte_matrix{app.plot_number},[],'all')...
                        *0.01*app.RelativeScaleSlider.Value;
                    clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                    app.RelativeScaleSlider.Enable = 'on';
                    app.EnableAbsoluteScaleCheckBox.Enable = 'on';
                case 1
                    A = app.fs_analyte_matrix{app.plot_number};

                    A(isinf(A)) = NaN;

                    
                    %sigma = std(A,[],'all','omitnan');
                    n = app.PercentileEditField.Value;
                    avg = prctile(A,n,'all');
                    if gt(avg,app.c_scale_1)
                        app.c_scale_2 = avg;

                    else
                        app.c_scale_2 = avg+1;

                    end
                    clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
                    app.RelativeScaleSlider.Enable = 'off';
                    app.EnableAbsoluteScaleCheckBox.Enable = 'off';

            end

            app.PercentileEditField.Enable ='on';
        end

        % Value changed function: LowerLimitEditField
        function LowerLimitEditFieldValueChanged(app, event)
            app.c_scale_1 = app.LowerLimitEditField.Value;
            clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);

        end

        % Value changed function: UpperLimitEditField
        function UpperLimitEditFieldValueChanged(app, event)
            app.c_scale_2 = app.UpperLimitEditField.Value;
            clim(app.UIAxes,[app.c_scale_1, app.c_scale_2]);
        end

        % Callback function
        function EnableCheckBox_2ValueChanged(app, event)
            value = app.EnableCheckBox_2.Value;

            switch value
                case 1
                    app.RelativeScaleSlider_2.Enable ='off';
                    app.LevelEditField.Enable = 'on';
                case 0
                    app.RelativeScaleSlider_2.Enable ='on';
                    app.LevelEditField.Enable = 'off';
            end

            mzfeaturesListBoxValueChanged(app,event)
        end

        % Callback function
        function LevelEditFieldValueChanged(app, event)
            value = app.LevelEditField.Value;

            A = app.current_image_untarget;

            A(isinf(A)) = NaN;

            avg = mean(A,'all','omitnan');
            sigma = std(A,[],'all','omitnan');
            n = app.LevelEditField.Value;
            clim(app.UIAxes_3,[app.c_scale_1, avg+n*sigma]);

        end

        % Callback function
        function kmeansROIButtonPushed(app, event)
            n = app.kEditField.Value;
            a = app.xEditField.Value;
            b = app.yEditField.Value*20;
            smooth_image = medfilt2(app.current_image_untarget,[a b]);

            [L,~] = imsegkmeans(single(smooth_image),n);
            app.kmeans_mask = L;
            imagesc(app.UIAxes_4,L)
            colormap(app.UIAxes_4,app.ColormapDropDown_3.Value);
        end

        % Callback function
        function kEditFieldValueChanged(app, event)

            ddItems = cell(app.kEditField.Value, 1);
            for ii = 1 : 1 : length(ddItems)
                ddItems{ii} = sprintf('%d', ii);
            end
            app.LayerDropDown.Items = ddItems;
            app.LayerDropDown.ItemsData = 1 : length(ddItems);
        end

        % Callback function
        function exportregionButtonPushed(app, event)
            export_mask = app.kmeans_mask;
            filter = (export_mask == app.LayerDropDown.Value);
            time_matrix_roi = double(export_mask).*filter.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            uisave('time_matrix_roi','filename for layer mask')
            uisave('list','filename for timestamps of ROI')
        end

        % Value changed function: ScanfilterDropDown
        function ScanfilterDropDownValueChanged(app, event)
            value = app.ScanfilterDropDown.Value

        end

        % Button pushed function: FindpeaksButton
        function FindpeaksButtonPushed(app, event)
            ppmgap = app.ppmtolEditField.Value;
            min_hits = app.minhitEditField.Value;
            min_int = app.minintEditField.Value;
            max_hits = app.maxhitEditField.Value;
            max_int = app.maxintEditField.Value;

            fold_thresh = app.FoldchangeEditField_2.Value;
            [app.peak_data,app.time,app.tic_filter,app.NumberOfScans] = sort_data_mzml(app.ScanfilterDropDown_3.Value,app.data_tmp);
            %try
            [k] = find_all_feat2(app.peak_data,ppmgap,min_hits,app.timematrix,app.region1,min_int,max_hits,max_int);
            [k2] = find_all_feat2(app.peak_data,ppmgap,5,app.timematrix,app.region2,0,120,max_int);

            [uni,upreg] = compare_regions(k,k2,min_hits,fold_thresh,ppmgap);
%             uni = uni(uni(:,5)>min_hits/100,:);
%             upreg = upreg(upreg(:,5)>min_hits/100,:);
% 
%             upreg = upreg(upreg(:,4)~=0,:);
%             uni = uni(uni(:,4)~=0,:);

            masses = [upreg(:,2);uni(:,2)];
            app.non_target_list = [upreg;uni];
            masses = sortrows(masses);

            [app.fs_analyte_matrix_untarget, app.fs_signal_inten_untarget] = mass_intensity_dev2(masses,ppmgap,...
                app.peak_data,app.NumberOfScans);

            [untarget_mat,untarget_time] = scanevent3(app.fs_signal_inten_untarget,app.time,masses);


            [app.fs_analyte_matrix_untarget,app.fs_time_simulated] = align_dev2(untarget_time,untarget_mat);

            app.tic_filter = tic_matrix(app.tic_filter,app.NumberOfScans);
            app.totalion =align_dev2(untarget_time,app.tic_filter);
            app.totalion = app.totalion{1};

            masses_string = string(masses);
            app.mz_str = masses_string;
            app.mzfeaturesListBox_2.Items = masses_string;
            app.index = masses;
            app.i = 1;
            o = 1:length(app.index);
            imagesc(app.UIAxes_5,app.fs_analyte_matrix_untarget{o(app.i)});
            app.numberofmzfeaturesEditField_2.Value = length(masses);
            colormap(app.UIAxes_5,app.ColormapDropDown_3.Value);
            app.mzEditField_2.Value = (app.index(app.i));
            app.mzfeaturesListBox_2.Value = app.mz_str(app.i);
            app.stored_Analyte_Matrix = app.fs_analyte_matrix_untarget;

            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.fs_analyte_matrix_untarget{o(app.i)},[],'all')...
                *0.01*app.RelativeScaleSlider_3.Value;
            clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);
            app.refmzEditField.Enable = 'off';
            mzfeaturesListBox_2ValueChanged(app, event)
            %            catch
            %                warndlg('There are no features found with the set parameters','Error')
            %            end
        end

        % Button pushed function: MainregionButton
        function MainregionButtonPushed(app, event)
            app.BW = 0;
            app.ROI = drawfreehand(app.UIAxes_5);
            app.BW = createMask(app.ROI);


            time_matrix_roi = app.BW.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            app.region1 = list;
        end

        % Button pushed function: ComparitorregionButton
        function ComparitorregionButtonPushed(app, event)
            app.BW = 0;
            app.ROI = drawfreehand(app.UIAxes_5);
            app.BW = createMask(app.ROI);


            time_matrix_roi = app.BW.*app.fs_time_simulated;
            time_matrix_roi(time_matrix_roi==0)=NaN;
            list = zeros(size(time_matrix_roi,1),3);
            for j = 1:size(time_matrix_roi,1)
                minimum_time = min(time_matrix_roi(j,:));
                maximum_time = max(time_matrix_roi(j,:));
                list(j,1) = j;
                list(j,2) = minimum_time/60;
                list(j,3) = maximum_time/60;
            end
            app.region2 = list;

        end

        % Callback function
        function ScanEventsEditField_3ValueChanged(app, event)
            %             value = app.ScanEventsEditField_3.Value;
            %             ddItems = cell(value, 1);
            %             for ii = 1 : 1 : length(ddItems)
            %                 ddItems{ii} = sprintf('%d', ii);
            %             end
            %             app.ScanfilterDropDown_3.Items = ddItems;
            %             app.ScanfilterDropDown_3.ItemsData = 1 : length(ddItems);
        end

        % Value changed function: mzfeaturesListBox_2
        function mzfeaturesListBox_2ValueChanged(app, event)
            masses = app.index;

            app.mzfeaturesListBox_2.Items = app.mz_str;
            highlighted_mass = app.mzfeaturesListBox_2.Value;

            mass = str2double(highlighted_mass);
            [~, mz_idx] = min(abs(masses - mass));

            app.i = mz_idx;
            app.current_image_untarget = app.fs_analyte_matrix_untarget{app.i};
            imagesc(app.UIAxes_5,app.current_image_untarget);
            app.mzEditField_2.Value = app.index(app.i);
            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                *0.01*app.RelativeScaleSlider_3.Value;
            clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);

            switch app.EnableCheckBox_3.Value
                case 1
                    A = app.current_image_untarget;
                    A(isinf(A)) = NaN;
                    n = app.PercentileEditField_2.Value;
                    avg = prctile(A,n,'all');
                    %sigma = std(A,[],'all','omitnan');
                    if gt(avg,app.c_scale_1)
                        app.c_scale_2 = avg;
                        clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                        app.RelativeScaleSlider_3.Enable = 'off';
                    else
                        app.c_scale_2 = avg+1;
                        clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                        app.RelativeScaleSlider_3.Enable = 'off';
                    end
                    case 0
                        app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_untarget(~isinf(app.current_image_untarget)),[],'all')...
                        *0.01*app.RelativeScaleSlider_3.Value;
                    clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);

            end

            switch app.mzCheckBox_2.Value
                case 1
                    app.current_image_untarget = app.fs_analyte_matrix_untarget{app.i}./app.norm_ion{1};
                    imagesc(app.UIAxes_5,app.current_image_untarget);
                    app.mzEditField_2.Value = app.index(app.i);
                    app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_untarget(~isinf(app.current_image_untarget)),[],'all')...
                        *0.01*app.RelativeScaleSlider_3.Value;
                    clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);
                    switch app.EnableCheckBox_3.Value
                        case 1
                            A = app.current_image_untarget;
                            A(isinf(A)) = NaN;
                            n = app.PercentileEditField_2.Value;
                            avg = prctile(A,n,'all');
                            %sigma = std(A,[],'all','omitnan');
                            if gt(avg,app.c_scale_1)
                                app.c_scale_2 = avg;
                                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                                app.RelativeScaleSlider_3.Enable = 'off';
                            else
                                app.c_scale_2 = avg+1;
                                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                                app.RelativeScaleSlider_3.Enable = 'off';
                            end
                        case 0
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget(~isinf(app.current_image_untarget)),[],'all')...
                                *0.01*app.RelativeScaleSlider_3.Value;
                            clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);

                    end

            end


            switch app.TICnormCheckBox_2.Value
                case 1
                    app.current_image_untarget = app.fs_analyte_matrix_untarget{app.i}./app.totalion;
                    imagesc(app.UIAxes_5,app.current_image_untarget);
                    app.mzEditField_2.Value = app.index(app.i);
                    app.c_scale_1 = 0;
                    app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                        *0.01*app.RelativeScaleSlider_3.Value;
                    clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);
                    switch app.EnableCheckBox_3.Value
                        case 1
                            A = app.current_image_untarget;
                            A(isinf(A)) = NaN;
                            n = app.PercentileEditField_2.Value;
                            avg = prctile(A,n,'all');
                            %sigma = std(A,[],'all','omitnan');
                            if gt(avg,app.c_scale_1)
                                app.c_scale_2 = avg;
                                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                                app.RelativeScaleSlider_3.Enable = 'off';
                            else
                                app.c_scale_2 = avg+1;
                                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
                                app.RelativeScaleSlider_3.Enable = 'off';
                            end
                        case 0
                            app.c_scale_1 = 0;
                            app.c_scale_2 = max(app.current_image_untarget,[],'all')...
                                *0.01*app.RelativeScaleSlider_3.Value;
                            clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);

                    end
            end


        end

        % Value changed function: EnableCheckBox_3
        function EnableCheckBox_3ValueChanged(app, event)
            value = app.EnableCheckBox_3.Value;


            switch value
                case 1
                    app.RelativeScaleSlider_3.Enable ='off';
                    app.PercentileEditField_2.Enable = 'on';
                case 0
                    app.RelativeScaleSlider_3.Enable ='on';
                    app.PercentileEditField_2.Enable = 'off';
            end

            mzfeaturesListBox_2ValueChanged(app,event)
        end

        % Value changed function: PercentileEditField_2
        function PercentileEditField_2ValueChanged(app, event)
            n = app.PercentileEditField_2.Value;

            A = app.current_image_untarget;

            A(isinf(A)) = NaN;
            
            %avg = mean(A,'all','omitnan');
            %sigma = std(A,[],'all','omitnan');
            app.c_scale_2 = prctile(A,n,'all');

            if gt(app.c_scale_2,app.c_scale_1)
                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
            else
                app.c_scale_2 = app.c_scale_2+1;
                clim(app.UIAxes_5,[app.c_scale_1, app.c_scale_2]);
            end
            mzfeaturesListBox_2ValueChanged(app, event)
        end

        % Value changed function: TICnormCheckBox_2
        function TICnormCheckBox_2ValueChanged(app, event)

            mzfeaturesListBox_2ValueChanged(app,event)
        end

        % Value changing function: RelativeScaleSlider_3
        function RelativeScaleSlider_3ValueChanging(app, event)
            changingValue = event.Value;

            app.c_scale_1 = 0;
            app.c_scale_2 = max(app.current_image_untarget(~isinf(app.current_image_untarget)),[],'all')...
                *0.01*changingValue;
            clim(app.UIAxes_5,[app.c_scale_1,app.c_scale_2]);

        end

        % Value changed function: ColormapDropDown_3
        function ColormapDropDown_3ValueChanged(app, event)
            value = app.ColormapDropDown_3.Value;
            colormap(app.UIAxes_5,value);
        end

        % Button pushed function: Button_5
        function Button_5Pushed(app, event)
            if app.i < max(length(app.index))
                app.i=app.i+1;
                app.mzEditField_2.Value = (app.index(app.i));
                app.mzfeaturesListBox_2.Value = app.mz_str(app.i);
            else
                app.i = 1;
                app.mzEditField_2.Value = (app.index(app.i));
                app.mzfeaturesListBox_2.Value = app.mz_str(app.i);
            end
            mzfeaturesListBox_2ValueChanged(app,event)


        end

        % Button pushed function: Button_6
        function Button_6Pushed(app, event)
            if app.i >= 1.5
                app.i=app.i-1;
                app.mzEditField_2.Value = (app.index(app.i));
                app.mzfeaturesListBox_2.Value = app.mz_str(app.i);
            else
                app.i = 1;
                app.mzEditField_2.Value = (app.index(app.i));
                app.mzfeaturesListBox_2.Value = app.mz_str(app.i);
            end
            mzfeaturesListBox_2ValueChanged(app,event)


        end

        % Value changed function: EditField_4
        function EditField_4ValueChanged(app, event)
            norm_mz = app.EditField_4.Value;

            fs = app.peak_data;

            [norm_mat,normalize_ion] = mass_intensity_dev2(norm_mz,...
                app.ppmtolEditField.Value,fs,app.NumberOfScans);
            [norm_mat,norm_time] = scanevent3(normalize_ion,app.time,norm_mz);
            [app.norm_ion,~] = align_dev2(norm_time,norm_mat);

            mzfeaturesListBox_2ValueChanged(app,event)
        end

        % Value changed function: mzCheckBox_2
        function mzCheckBox_2ValueChanged(app, event)
            value = app.mzCheckBox_2.Value;
            switch value
                case 1


                    app.TICnormCheckBox_2.Value = 0;
                    app.TICnormCheckBox_2.Enable = 'off';
                    norm_mz = app.EditField_4.Value;

                    fs = app.peak_data;

                    [norm_mat,normalize_ion] = mass_intensity_dev2(norm_mz,...
                        app.ppmtolEditField.Value,fs,app.NumberOfScans);
                    [norm_mat,norm_time] = scanevent3(normalize_ion,app.time,norm_mz);
                    [app.norm_ion,~] = align_dev2(norm_time,norm_mat);

                case 0
                    app.fs_analyte_matrix_untarget = app.stored_Analyte_Matrix;
                    app.TICnormCheckBox_2.Enable = 'on';
                    app.norm_ion = [];
            end
            mzfeaturesListBox_2ValueChanged(app,event)
        end

        % Value changed function: refmzEditField
        function refmzEditFieldValueChanged(app, event)
            value = app.refmzEditField.Value;


            fs = app.peak_data;

            [app.peak_data,app.time,app.tic_filter,app.NumberOfScans] = sort_data_mzml(app.ScanfilterDropDown_3.Value,app.data_tmp);
            app.timematrix = timematrix_func(app.time);
            [~,ref_mz] = mass_intensity_dev2(value,app.ppmtolEditField.Value,app.peak_data,app.NumberOfScans);
            [ref_image,norm_time] = scanevent3(ref_mz,app.time,value);
            [ref_image,app.fs_time_simulated] = align_dev2(norm_time,ref_image);
            imagesc(app.UIAxes_5,ref_image{1})
            colormap(app.UIAxes_5,app.ColormapDropDown_3.Value);
        end

        % Value changed function: Switch_2
        function Switch_2ValueChanged(app, event)
            value = app.Switch_2.Value;

            if value == 'Load'
                app.MainregionButton.Enable = 'off';
                app.ComparitorregionButton.Enable = 'off';
                app.LoadregionsButton_2.Enable = 'on';
                app.refmzEditField.Enable = 'off'
            end
            if value == 'Draw'
                app.LoadregionsButton_2.Enable = 'off';
                app.MainregionButton.Enable = 'on';
                app.ComparitorregionButton.Enable = 'on';
                app.refmzEditField.Enable = 'on'
            end

        end

        % Button pushed function: UpdateaspectratioButton_3
        function UpdateaspectratioButton_3Pushed(app, event)
            x = aspect_ratio(app.time,app.ProbespeedmsEditField_2.Value,app.StepsizemEditField_2.Value);
            pbaspect(app.UIAxes_5,[x 1 1]);

        end

        % Button pushed function: SaveimageButton_2
        function SaveimageButton_2Pushed(app, event)
            fig = figure('visible','off');
            h = copyUIAxes(app.UIAxes_5,fig);

            current_dir = pwd;
            dir = uigetdir;
            cd(dir)
            a = exist(strcat(app.mzfeaturesListBox_2.Value,app.FiletypeDropDown_2.Value),'file');


            if a == 2
                exportgraphics(app.UIAxes_5,strcat(app.mzfeaturesListBox_2.Value,...
                    datestr(now,'mm-dd-yyyy HH-MM'),app.FiletypeDropDown_2.Value),...
                    'BackgroundColor','none','ContentType','vector','Resolution',300);

            else
                exportgraphics(app.UIAxes_5,strcat(app.mzfeaturesListBox_2.Value,...
                    app.FiletypeDropDown_2.Value),'BackgroundColor','none','ContentType','vector','Resolution',300);


            end
            cd(current_dir)
        end

        % Button pushed function: SaveCSVFileButton_2
        function SaveCSVFileButton_2Pushed(app, event)
            current_dir = pwd;
            dir = uigetdir;
            cd(dir)

            a = exist(strcat(app.mzfeaturesListBox_2.Value,'.csv'),'file');
            if a == 2
                csvwrite(strcat(app.mzfeaturesListBox_2.Value,...
                    datestr(now,'mm-dd-yyyy HH-MM'),'.csv'),app.current_image_untarget)
            else
                csvwrite(strcat(app.mzfeaturesListBox_2.Value,'.csv'),app.current_image_untarget)
            end
            cd(current_dir)
        end

        % Button pushed function: SavematFileButton_2
        function SavematFileButton_2Pushed(app, event)
            current_dir = pwd;
            dir = uigetdir;
            cd(dir)

            a = exist(strcat(app.mzfeaturesListBox_2.Value,'.mat'),'file');

            if a == 2
                cp = app.current_image_untarget;
                save(strcat(app.mzfeaturesListBox_2.Value,...
                    datestr(now,'mm-dd-yyyy HH-MM'),'.mat'),'cp')
            else
                cp = app.current_image_untarget;
                save(strcat(app.mzfeaturesListBox_2.Value,'.mat'),'cp')
            end
            cd(current_dir)
        end

        % Button pushed function: SavefeaturetolistButton_2
        function SavefeaturetolistButton_2Pushed(app, event)
            current_feature = str2double(app.mzfeaturesListBox_2.Value);
            app.interesting_feature = [app.interesting_feature current_feature];
            app.ListBox_2.Items = string(app.interesting_feature);
        end

        % Button pushed function: ExportsavedfeaturesButton_2
        function ExportsavedfeaturesButton_2Pushed(app, event)
            interest = app.interesting_feature;
            name = {'interesting features',datestr(now,'mm-dd-yyyy HH-MM'),'.csv'};

            current_dir = pwd;
            dir = uigetdir;
            cd(dir)
            writematrix(interest',strjoin(name));
            cd(current_dir)
        end

        % Button pushed function: ClearsavedfeaturesButton_2
        function ClearsavedfeaturesButton_2Pushed(app, event)
            choice = questdlg('Are you sure you want to clear feature list?','','Yes','No','');

            switch choice
                case 'Yes'
                    app.interesting_feature = {};
                    app.ListBox_2.Items = string(app.interesting_feature);
                case 'No'
                    return
                case 'Cancel'
                    return
                case ''
                    return
            end


        end

        % Button pushed function: ResetDataButton_2
        function ResetDataButton_2Pushed(app, event)

            cla(app.UIAxes_5)
            app.fs_analyte_matrix_untarget  = [];


            app.mzfeaturesListBox_2.Items = {};
            app.mzEditField_2.Value = 0.0000;
            app.numberofmzfeaturesEditField_2.Value = 0.0000;

        end

        % Button pushed function: LoadregionsButton_2
        function LoadregionsButton_2Pushed(app, event)
            [regionfile,regionpath] = uigetfile('.','Select file 1');
            path_plus_file = strcat(regionpath,regionfile);
            load(path_plus_file);
            app.region1 = list;

            [regionfile,regionpath] = uigetfile('.','Select file 2');
            path_plus_file = strcat(regionpath,regionfile);
            load(path_plus_file);
            app.region2 =  list;

        end

        % Value changed function: ScanfilterDropDown_3
        function ScanfilterDropDown_3ValueChanged(app, event)
            value = app.ScanfilterDropDown_3.Value;

        end

        % Value changed function: minhitEditField
        function minhitEditFieldValueChanged(app, event)
            value = app.minhitEditField.Value;

        end

        % Value changed function: ColorbarCheckBox
        function ColorbarCheckBoxValueChanged(app, event)
            value = app.ColorbarCheckBox.Value;
            switch value
                case 1
                    colorbar(app.UIAxes);
                case 0
                    colorbar(app.UIAxes,'delete');

            end
        end

        % Button pushed function: 
        % ExportdatafromnontargetedcalculationsButton
        function ExportdatafromnontargetedcalculationsButtonPushed(app, event)
            non_target = app.non_target_list;
            name = {'non-target feature list',datestr(now,'mm-dd-yyyy HH-MM'),'.csv'};
            header = {'Feature group', 'Average m/z','Standard deviation of m/z','max - min of m/z',...
                'Average Intensity','Max intensity','Standard deviation of intensity','Number of spectra','Fold change (region 1 over region 2)','t-value'};
            non_target = non_target(:,[1,2,3,4,6,7,8,9,10,11]);
            non_target = array2table(non_target);
            non_target.Properties.VariableNames = header;
            current_dir = pwd;
            dir = uigetdir;
            cd(dir)
            writetable(non_target,strjoin(name));
            cd(current_dir)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.i2iUIFigure = uifigure('Visible', 'off');
            app.i2iUIFigure.Name = 'i2i';
            app.TabGroup = uitabgroup(app.i2iUIFigure);
            app.TargetedTab = uitab(app.TabGroup);
            app.TargetedTab.Title = 'Targeted';
            app.UIAxes = uiaxes(app.TargetedTab);
            app.UIAxes.XTick = [];
            app.UIAxes.YTick = [];
            app.UIAxes.Visible = 'off';
            app.QuantificationPanel = uipanel(app.TargetedTab);
            app.QuantificationPanel.Title = 'Quantification';
            app.ConcEditFieldLabel = uilabel(app.QuantificationPanel);
            app.ConcEditFieldLabel.Enable = 'off';
            app.ConcEditFieldLabel.Text = 'Conc.';
            app.ConcEditField = uieditfield(app.QuantificationPanel, 'numeric');
            app.ConcEditField.ValueChangedFcn = createCallbackFcn(app, @ConcEditFieldValueChanged, true);
            app.ConcEditField.Enable = 'off';
            app.ConcEditField.Value = 1;
            app.RespfacEditFieldLabel = uilabel(app.QuantificationPanel);
            app.RespfacEditFieldLabel.Enable = 'off';
            app.RespfacEditFieldLabel.Text = 'Resp. fac.';
            app.RespfacEditField = uieditfield(app.QuantificationPanel, 'numeric');
            app.RespfacEditField.Limits = [0 Inf];
            app.RespfacEditField.ValueChangedFcn = createCallbackFcn(app, @RespfacEditFieldValueChanged, true);
            app.RespfacEditField.Enable = 'off';
            app.RespfacEditField.Value = 1;
            app.AutomaticcontrastadjustmentPanel = uipanel(app.TargetedTab);
            app.AutomaticcontrastadjustmentPanel.Title = 'Automatic contrast adjustment';
            app.PercentileEditFieldLabel = uilabel(app.AutomaticcontrastadjustmentPanel);
            app.PercentileEditFieldLabel.Enable = 'off';
            app.PercentileEditFieldLabel.Text = 'Percentile';
            app.PercentileEditField = uieditfield(app.AutomaticcontrastadjustmentPanel, 'numeric');
            app.PercentileEditField.ValueChangedFcn = createCallbackFcn(app, @PercentileEditFieldValueChanged, true);
            app.PercentileEditField.Enable = 'off';
            app.PercentileEditField.Value = 99;
            app.EnableCheckBox = uicheckbox(app.AutomaticcontrastadjustmentPanel);
            app.EnableCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBoxValueChanged, true);
            app.EnableCheckBox.Text = 'Enable';
            app.RegionofintrestPanel = uipanel(app.TargetedTab);
            app.RegionofintrestPanel.Title = 'Region of intrest';
            app.UIAxes_2 = uiaxes(app.RegionofintrestPanel);
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_2.Visible = 'off';
            app.GetROIButton = uibutton(app.RegionofintrestPanel, 'push');
            app.GetROIButton.ButtonPushedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.GetROIButton.Text = 'Get ROI';
            app.meanEditFieldLabel = uilabel(app.RegionofintrestPanel);
            app.meanEditFieldLabel.Text = 'mean';
            app.meanEditField = uieditfield(app.RegionofintrestPanel, 'numeric');
            app.meanEditField.ValueChangedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.EditField_5Label = uilabel(app.RegionofintrestPanel);
            app.EditField_5Label.Interpreter = 'html';
            app.EditField_5Label.Text = 'σ';
            app.EditField_5 = uieditfield(app.RegionofintrestPanel, 'numeric');
            app.EditField_5.ValueChangedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.UpdateROIButton = uibutton(app.RegionofintrestPanel, 'push');
            app.UpdateROIButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateROIButtonPushed, true);
            app.UpdateROIButton.Text = 'Update ROI';
            app.SaveROImaskButton = uibutton(app.RegionofintrestPanel, 'push');
            app.SaveROImaskButton.ButtonPushedFcn = createCallbackFcn(app, @SaveROImaskButtonPushed, true);
            app.SaveROImaskButton.Text = 'Save ROI mask';
            app.LoadROImaskButton = uibutton(app.RegionofintrestPanel, 'push');
            app.LoadROImaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadROImaskButtonPushed, true);
            app.LoadROImaskButton.Text = 'Load ROI mask';
            app.applytoallCheckBox = uicheckbox(app.RegionofintrestPanel);
            app.applytoallCheckBox.ValueChangedFcn = createCallbackFcn(app, @applytoallCheckBoxValueChanged, true);
            app.applytoallCheckBox.Enable = 'off';
            app.applytoallCheckBox.Text = 'apply to all';
            app.medianEditFieldLabel = uilabel(app.RegionofintrestPanel);
            app.medianEditFieldLabel.Text = 'median';
            app.medianEditField = uieditfield(app.RegionofintrestPanel, 'numeric');
            app.medianEditField.ValueChangedFcn = createCallbackFcn(app, @ROIButtonPushed, true);
            app.ClearROIButton = uibutton(app.RegionofintrestPanel, 'push');
            app.ClearROIButton.ButtonPushedFcn = createCallbackFcn(app, @ClearROIButtonPushed, true);
            app.ClearROIButton.Text = 'Clear ROI';
            app.nEditFieldLabel = uilabel(app.RegionofintrestPanel);
            app.nEditFieldLabel.Text = 'n';
            app.nEditField = uieditfield(app.RegionofintrestPanel, 'numeric');
            app.nEditField.Editable = 'off';
            app.SaverawdataCheckBox = uicheckbox(app.RegionofintrestPanel);
            app.SaverawdataCheckBox.Text = 'Save raw data';
            app.AspectratioPanel = uipanel(app.TargetedTab);
            app.AspectratioPanel.Title = 'Aspect ratio';
            app.StepsizemLabel = uilabel(app.AspectratioPanel);
            app.StepsizemLabel.Interpreter = 'html';
            app.StepsizemLabel.Text = {'Step size (μm)'; ''; ''};
            app.ProbespeedmsLabel = uilabel(app.AspectratioPanel);
            app.ProbespeedmsLabel.Interpreter = 'html';
            app.ProbespeedmsLabel.Text = 'Probe speed (μm/s)';
            app.ProbespeedmsEditField = uieditfield(app.AspectratioPanel, 'numeric');
            app.StepsizemEditField = uieditfield(app.AspectratioPanel, 'numeric');
            app.UpdateaspectratioButton = uibutton(app.AspectratioPanel, 'push');
            app.UpdateaspectratioButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateaspectratioButtonPushed, true);
            app.UpdateaspectratioButton.Text = 'Update aspect ratio';
            app.AbsoluteScalePanel = uipanel(app.TargetedTab);
            app.AbsoluteScalePanel.Title = 'Absolute Scale';
            app.LowerLimitEditFieldLabel = uilabel(app.AbsoluteScalePanel);
            app.LowerLimitEditFieldLabel.Text = 'Lower Limit';
            app.LowerLimitEditField = uieditfield(app.AbsoluteScalePanel, 'numeric');
            app.LowerLimitEditField.ValueChangedFcn = createCallbackFcn(app, @LowerLimitEditFieldValueChanged, true);
            app.LowerLimitEditField.Enable = 'off';
            app.UpperLimitEditFieldLabel = uilabel(app.AbsoluteScalePanel);
            app.UpperLimitEditFieldLabel.Text = 'Upper Limit';
            app.UpperLimitEditField = uieditfield(app.AbsoluteScalePanel, 'numeric');
            app.UpperLimitEditField.ValueChangedFcn = createCallbackFcn(app, @UpperLimitEditFieldValueChanged, true);
            app.UpperLimitEditField.Enable = 'off';
            app.EnableAbsoluteScaleCheckBox = uicheckbox(app.AbsoluteScalePanel);
            app.EnableAbsoluteScaleCheckBox.ValueChangedFcn = createCallbackFcn(app, @EnableAbsoluteScaleCheckBoxValueChanged2, true);
            app.EnableAbsoluteScaleCheckBox.Text = {'Enable abs. scale'; ''};
            app.NormalizationPanel = uipanel(app.TargetedTab);
            app.NormalizationPanel.Title = 'Normalization';
            app.TICCheckBox = uicheckbox(app.NormalizationPanel);
            app.TICCheckBox.ValueChangedFcn = createCallbackFcn(app, @TICCheckBoxValueChanged, true);
            app.TICCheckBox.Enable = 'off';
            app.TICCheckBox.Text = 'TIC';
            app.mzCheckBox = uicheckbox(app.NormalizationPanel);
            app.mzCheckBox.ValueChangedFcn = createCallbackFcn(app, @mzCheckBoxValueChanged, true);
            app.mzCheckBox.Enable = 'off';
            app.mzCheckBox.Text = 'm/z';
            app.EditField_2 = uieditfield(app.NormalizationPanel, 'numeric');
            app.EditField_2.Limits = [0 Inf];
            app.EditField_2.ValueDisplayFormat = '%.4f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.EditField_2.Enable = 'off';
            app.display_mz = uieditfield(app.TargetedTab, 'numeric');
            app.display_mz.ValueDisplayFormat = '%.4f';
            app.display_mz.Editable = 'off';
            app.Button_2 = uibutton(app.TargetedTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = '>';
            app.Button = uibutton(app.TargetedTab, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Text = '<';
            app.EditField = uieditfield(app.TargetedTab, 'text');
            app.EditField.Editable = 'off';
            app.EditField.Value = 'Ready';
            app.ResetDataButton = uibutton(app.TargetedTab, 'push');
            app.ResetDataButton.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButtonPushed, true);
            app.ResetDataButton.Text = 'Reset Data';
            app.Lamp_3 = uilamp(app.TargetedTab);
            app.FindPeaksButton = uibutton(app.TargetedTab, 'push');
            app.FindPeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindPeaksButtonPushed, true);
            app.FindPeaksButton.Enable = 'off';
            app.FindPeaksButton.Text = 'Find Peaks';
            app.Lamp_2 = uilamp(app.TargetedTab);
            app.LoadAnalyteListButton = uibutton(app.TargetedTab, 'push');
            app.LoadAnalyteListButton.ButtonPushedFcn = createCallbackFcn(app, @LoadAnalyteListButtonPushed, true);
            app.LoadAnalyteListButton.Enable = 'off';
            app.LoadAnalyteListButton.Text = 'Load Analyte List';
            app.Lamp = uilamp(app.TargetedTab);
            app.ResetAllButton = uibutton(app.TargetedTab, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Text = 'Reset All';
            app.LoadmzMLFilesButton = uibutton(app.TargetedTab, 'push');
            app.LoadmzMLFilesButton.ButtonPushedFcn = createCallbackFcn(app, @LoadmzMLFilesButtonPushed, true);
            app.LoadmzMLFilesButton.Text = 'Load mzML Files';
            app.offilesEditFieldLabel = uilabel(app.TargetedTab);
            app.offilesEditFieldLabel.Text = '# of files';
            app.offilesEditField = uieditfield(app.TargetedTab, 'numeric');
            app.offilesEditField.Editable = 'off';
            app.ofanalytesEditFieldLabel = uilabel(app.TargetedTab);
            app.ofanalytesEditFieldLabel.Text = '# of analytes';
            app.ofanalytesEditField = uieditfield(app.TargetedTab, 'numeric');
            app.ofanalytesEditField.Limits = [0 Inf];
            app.ofanalytesEditField.ValueChangedFcn = createCallbackFcn(app, @ofanalytesEditFieldValueChanged, true);
            app.ofanalytesEditField.Editable = 'off';
            app.ToleranceEditFieldLabel = uilabel(app.TargetedTab);
            app.ToleranceEditFieldLabel.Text = 'Tolerance';
            app.ToleranceEditField = uieditfield(app.TargetedTab, 'numeric');
            app.ToleranceEditField.Limits = [0 Inf];
            app.ToleranceEditField.ValueChangedFcn = createCallbackFcn(app, @ToleranceEditFieldValueChanged, true);
            app.ToleranceEditField.Value = 5;
            app.SaveCSVFileButton = uibutton(app.TargetedTab, 'push');
            app.SaveCSVFileButton.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVFileButtonPushed, true);
            app.SaveCSVFileButton.Text = 'Save CSV File';
            app.SavematFileButton = uibutton(app.TargetedTab, 'push');
            app.SavematFileButton.ButtonPushedFcn = createCallbackFcn(app, @SavematFileButtonPushed, true);
            app.SavematFileButton.Text = 'Save .mat File';
            app.SaveimageButton = uibutton(app.TargetedTab, 'push');
            app.SaveimageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButtonPushed, true);
            app.SaveimageButton.Text = 'Save image';
            app.mzListBoxLabel = uilabel(app.TargetedTab);
            app.mzListBoxLabel.Text = 'm/z';
            app.mzListBox = uilistbox(app.TargetedTab);
            app.mzListBox.Items = {};
            app.mzListBox.ValueChangedFcn = createCallbackFcn(app, @mzListBoxValueChangedd, true);
            app.mzListBox.Value = {};
            app.RelativeScaleSliderLabel = uilabel(app.TargetedTab);
            app.RelativeScaleSliderLabel.Text = {'Relative'; 'Scale'};
            app.RelativeScaleSlider = uislider(app.TargetedTab);
            app.RelativeScaleSlider.ValueChangedFcn = createCallbackFcn(app, @RelativeScaleSliderValueChanged, true);
            app.RelativeScaleSlider.ValueChangingFcn = createCallbackFcn(app, @RelativeScaleSliderValueChanging, true);
            app.RelativeScaleSlider.Value = 100;
            app.ColormapDropDownLabel = uilabel(app.TargetedTab);
            app.ColormapDropDownLabel.Text = 'Colormap';
            app.ColormapDropDown = uidropdown(app.TargetedTab);
            app.ColormapDropDown.Items = {'Viridis', 'Gray', 'Parula', 'Hot', 'Jet'};
            app.ColormapDropDown.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDownValueChanged, true);
            app.ColormapDropDown.Value = 'Viridis';
            app.ScanfilterDropDownLabel = uilabel(app.TargetedTab);
            app.ScanfilterDropDownLabel.Text = 'Scan filter';
            app.ScanfilterDropDown = uidropdown(app.TargetedTab);
            app.ScanfilterDropDown.Items = {'No filter loaded'};
            app.ScanfilterDropDown.ValueChangedFcn = createCallbackFcn(app, @ScanfilterDropDownValueChanged, true);
            app.ScanfilterDropDown.Value = 'No filter loaded';
            app.AnalytenameEditFieldLabel = uilabel(app.TargetedTab);
            app.AnalytenameEditFieldLabel.Text = 'Analyte name';
            app.AnalytenameEditField = uieditfield(app.TargetedTab, 'text');
            app.AnalytenameEditField.Editable = 'off';
            app.ColorbarCheckBox = uicheckbox(app.TargetedTab);
            app.ColorbarCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorbarCheckBoxValueChanged, true);
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.FiletypeDropDownLabel = uilabel(app.TargetedTab);
            app.FiletypeDropDownLabel.Text = 'File type';
            app.FiletypeDropDown = uidropdown(app.TargetedTab);
            app.FiletypeDropDown.Items = {'.eps', '.png', '.tiff'};
            app.FiletypeDropDown.Value = '.eps';
            app.NontargetedTab = uitab(app.TabGroup);
            app.NontargetedTab.Title = 'Non-targeted';
            app.UIAxes_5 = uiaxes(app.NontargetedTab);
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_5.Visible = 'off';
            app.FeaturedetectionparametersPanel = uipanel(app.NontargetedTab);
            app.FeaturedetectionparametersPanel.Title = 'Feature detection parameters';
            app.FindpeaksButton = uibutton(app.FeaturedetectionparametersPanel, 'push');
            app.FindpeaksButton.ButtonPushedFcn = createCallbackFcn(app, @FindpeaksButtonPushed, true);
            app.FindpeaksButton.Text = 'Find peaks';
            app.ppmtolEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.ppmtolEditFieldLabel.Text = 'ppm tol';
            app.ppmtolEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.ppmtolEditField.Value = 5;
            app.minhitEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.minhitEditFieldLabel.Text = 'min hit (%)';
            app.minhitEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.minhitEditField.Limits = [0 100];
            app.minhitEditField.ValueChangedFcn = createCallbackFcn(app, @minhitEditFieldValueChanged, true);
            app.minhitEditField.Value = 10;
            app.minintEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.minintEditFieldLabel.Text = 'min int';
            app.minintEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.minintEditField.Limits = [0 Inf];
            app.minintEditField.Value = 100000;
            app.ScanfilterDropDown_2Label_2 = uilabel(app.FeaturedetectionparametersPanel);
            app.ScanfilterDropDown_2Label_2.Text = 'Scan filter';
            app.ScanfilterDropDown_3 = uidropdown(app.FeaturedetectionparametersPanel);
            app.ScanfilterDropDown_3.Items = {'No filter loaded'};
            app.ScanfilterDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ScanfilterDropDown_3ValueChanged, true);
            app.ScanfilterDropDown_3.Value = 'No filter loaded';
            app.FoldchangeEditField_2Label = uilabel(app.FeaturedetectionparametersPanel);
            app.FoldchangeEditField_2Label.Text = 'Fold change';
            app.FoldchangeEditField_2 = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.FoldchangeEditField_2.Value = 2;
            app.Switch_2 = uiswitch(app.FeaturedetectionparametersPanel, 'slider');
            app.Switch_2.Items = {'Load', 'Draw'};
            app.Switch_2.ValueChangedFcn = createCallbackFcn(app, @Switch_2ValueChanged, true);
            app.Switch_2.Value = 'Load';
            app.refmzEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.refmzEditFieldLabel.Enable = 'off';
            app.refmzEditFieldLabel.Text = 'ref. m/z';
            app.refmzEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.refmzEditField.ValueDisplayFormat = '%.4f';
            app.refmzEditField.ValueChangedFcn = createCallbackFcn(app, @refmzEditFieldValueChanged, true);
            app.refmzEditField.Enable = 'off';
            app.LoadregionsButton_2 = uibutton(app.FeaturedetectionparametersPanel, 'push');
            app.LoadregionsButton_2.ButtonPushedFcn = createCallbackFcn(app, @LoadregionsButton_2Pushed, true);
            app.LoadregionsButton_2.Text = 'Load regions';
            app.MainregionButton = uibutton(app.FeaturedetectionparametersPanel, 'push');
            app.MainregionButton.ButtonPushedFcn = createCallbackFcn(app, @MainregionButtonPushed, true);
            app.MainregionButton.Enable = 'off';
            app.MainregionButton.Text = 'Main region';
            app.ComparitorregionButton = uibutton(app.FeaturedetectionparametersPanel, 'push');
            app.ComparitorregionButton.ButtonPushedFcn = createCallbackFcn(app, @ComparitorregionButtonPushed, true);
            app.ComparitorregionButton.Enable = 'off';
            app.ComparitorregionButton.Text = 'Comparitor region';
            app.maxhitEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.maxhitEditFieldLabel.Text = 'max hit(%)';
            app.maxhitEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.maxhitEditField.Limits = [0 105];
            app.maxhitEditField.Value = 100;
            app.maxintEditFieldLabel = uilabel(app.FeaturedetectionparametersPanel);
            app.maxintEditFieldLabel.Text = 'max int';
            app.maxintEditField = uieditfield(app.FeaturedetectionparametersPanel, 'numeric');
            app.maxintEditField.Limits = [0 Inf];
            app.maxintEditField.Value = 1000000000;
            app.Button_5 = uibutton(app.NontargetedTab, 'push');
            app.Button_5.ButtonPushedFcn = createCallbackFcn(app, @Button_5Pushed, true);
            app.Button_5.Text = '>';
            app.Button_6 = uibutton(app.NontargetedTab, 'push');
            app.Button_6.ButtonPushedFcn = createCallbackFcn(app, @Button_6Pushed, true);
            app.Button_6.Text = '<';
            app.TICnormCheckBox_2 = uicheckbox(app.NontargetedTab);
            app.TICnormCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @TICnormCheckBox_2ValueChanged, true);
            app.TICnormCheckBox_2.Text = 'TIC norm';
            app.AutomaticcontrastadjustmentPanel_3 = uipanel(app.NontargetedTab);
            app.AutomaticcontrastadjustmentPanel_3.Title = 'Automatic contrast adjustment';
            app.PercentileEditField_2Label = uilabel(app.AutomaticcontrastadjustmentPanel_3);
            app.PercentileEditField_2Label.Text = 'Percentile';
            app.PercentileEditField_2 = uieditfield(app.AutomaticcontrastadjustmentPanel_3, 'numeric');
            app.PercentileEditField_2.ValueChangedFcn = createCallbackFcn(app, @PercentileEditField_2ValueChanged, true);
            app.PercentileEditField_2.Enable = 'off';
            app.PercentileEditField_2.Value = 99;
            app.EnableCheckBox_3 = uicheckbox(app.AutomaticcontrastadjustmentPanel_3);
            app.EnableCheckBox_3.ValueChangedFcn = createCallbackFcn(app, @EnableCheckBox_3ValueChanged, true);
            app.EnableCheckBox_3.Text = 'Enable';
            app.mzEditField_2Label = uilabel(app.NontargetedTab);
            app.mzEditField_2Label.Text = 'm/z';
            app.mzEditField_2 = uieditfield(app.NontargetedTab, 'numeric');
            app.mzEditField_2.ValueDisplayFormat = '%.4f';
            app.mzEditField_2.Editable = 'off';
            app.ColormapDropDown_3Label = uilabel(app.NontargetedTab);
            app.ColormapDropDown_3Label.Text = 'Colormap';
            app.RelativeScaleSlider_3Label = uilabel(app.NontargetedTab);
            app.RelativeScaleSlider_3Label.Text = {'Relative'; 'Scale'};
            app.RelativeScaleSlider_3 = uislider(app.NontargetedTab);
            app.RelativeScaleSlider_3.ValueChangingFcn = createCallbackFcn(app, @RelativeScaleSlider_3ValueChanging, true);
            app.RelativeScaleSlider_3.Value = 100;
            app.ColormapDropDown_3 = uidropdown(app.NontargetedTab);
            app.ColormapDropDown_3.Items = {'Viridis', 'Gray', 'Parula', 'Hot', 'Jet'};
            app.ColormapDropDown_3.ValueChangedFcn = createCallbackFcn(app, @ColormapDropDown_3ValueChanged, true);
            app.ColormapDropDown_3.Value = 'Viridis';
            app.mzCheckBox_2 = uicheckbox(app.NontargetedTab);
            app.mzCheckBox_2.ValueChangedFcn = createCallbackFcn(app, @mzCheckBox_2ValueChanged, true);
            app.mzCheckBox_2.Text = 'm/z';
            app.EditField_4 = uieditfield(app.NontargetedTab, 'numeric');
            app.EditField_4.Limits = [0 Inf];
            app.EditField_4.ValueDisplayFormat = '%.4f';
            app.EditField_4.ValueChangedFcn = createCallbackFcn(app, @EditField_4ValueChanged, true);
            app.AspectratioPanel_3 = uipanel(app.NontargetedTab);
            app.AspectratioPanel_3.Title = 'Aspect ratio';
            app.ProbespeedmsEditField_2 = uieditfield(app.AspectratioPanel_3, 'numeric');
            app.StepsizemEditField_2Label = uilabel(app.AspectratioPanel_3);
            app.StepsizemEditField_2Label.Interpreter = 'html';
            app.StepsizemEditField_2Label.Text = 'Step size (μm)';
            app.ProbespeedmsEditField_2Label = uilabel(app.AspectratioPanel_3);
            app.ProbespeedmsEditField_2Label.Interpreter = 'html';
            app.ProbespeedmsEditField_2Label.Text = 'Probe speed (μm/s)';
            app.StepsizemEditField_2 = uieditfield(app.AspectratioPanel_3, 'numeric');
            app.UpdateaspectratioButton_3 = uibutton(app.AspectratioPanel_3, 'push');
            app.UpdateaspectratioButton_3.ButtonPushedFcn = createCallbackFcn(app, @UpdateaspectratioButton_3Pushed, true);
            app.UpdateaspectratioButton_3.Text = 'Update aspect ratio';
            app.mzfeaturesListBox_2 = uilistbox(app.NontargetedTab);
            app.mzfeaturesListBox_2.Items = {};
            app.mzfeaturesListBox_2.ValueChangedFcn = createCallbackFcn(app, @mzfeaturesListBox_2ValueChanged, true);
            app.mzfeaturesListBox_2.Value = {};
            app.numberofmzfeaturesEditField_2 = uieditfield(app.NontargetedTab, 'numeric');
            app.numberofmzfeaturesEditField_2.Editable = 'off';
            app.numberofmzfeaturesEditFieldLabel_2 = uilabel(app.NontargetedTab);
            app.numberofmzfeaturesEditFieldLabel_2.Text = '# features';
            app.SaveCSVFileButton_2 = uibutton(app.NontargetedTab, 'push');
            app.SaveCSVFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveCSVFileButton_2Pushed, true);
            app.SaveCSVFileButton_2.Text = 'Save CSV File';
            app.SavematFileButton_2 = uibutton(app.NontargetedTab, 'push');
            app.SavematFileButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavematFileButton_2Pushed, true);
            app.SavematFileButton_2.Text = 'Save .mat File';
            app.SaveimageButton_2 = uibutton(app.NontargetedTab, 'push');
            app.SaveimageButton_2.ButtonPushedFcn = createCallbackFcn(app, @SaveimageButton_2Pushed, true);
            app.SaveimageButton_2.Text = 'Save image';
            app.SavefeaturetolistButton_2 = uibutton(app.NontargetedTab, 'push');
            app.SavefeaturetolistButton_2.ButtonPushedFcn = createCallbackFcn(app, @SavefeaturetolistButton_2Pushed, true);
            app.SavefeaturetolistButton_2.Text = 'Save feature to list';
            app.ExportsavedfeaturesButton_2 = uibutton(app.NontargetedTab, 'push');
            app.ExportsavedfeaturesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ExportsavedfeaturesButton_2Pushed, true);
            app.ExportsavedfeaturesButton_2.Text = 'Export saved features';
            app.ClearsavedfeaturesButton_2 = uibutton(app.NontargetedTab, 'push');
            app.ClearsavedfeaturesButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClearsavedfeaturesButton_2Pushed, true);
            app.ClearsavedfeaturesButton_2.Text = 'Clear saved features';
            app.ResetDataButton_2 = uibutton(app.NontargetedTab, 'push');
            app.ResetDataButton_2.ButtonPushedFcn = createCallbackFcn(app, @ResetDataButton_2Pushed, true);
            app.ResetDataButton_2.Text = 'Reset Data';
            app.FiletypeDropDown_2Label = uilabel(app.NontargetedTab);
            app.FiletypeDropDown_2Label.Text = 'File type';
            app.FiletypeDropDown_2 = uidropdown(app.NontargetedTab);
            app.FiletypeDropDown_2.Items = {'.eps', '.png', '.tiff'};
            app.FiletypeDropDown_2.Value = '.eps';
            app.ExportdatafromnontargetedcalculationsButton = uibutton(app.NontargetedTab, 'push');
            app.ExportdatafromnontargetedcalculationsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportdatafromnontargetedcalculationsButtonPushed, true);
            app.ExportdatafromnontargetedcalculationsButton.Text = 'Export data from non-targeted calculations';
            app.ListBox_2 = uilistbox(app.NontargetedTab);
            app.ListBox_2.Items = {};
            app.ListBox_2.Enable = 'off';
            app.ListBox_2.Value = {};
            % Show the figure after all components are created
            app.i2iUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = i2i_app_ultra_mzml

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.i2iUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.i2iUIFigure)
        end
    end
end